classdef BRM_Microscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        AcquisitionStatusPanel         matlab.ui.container.Panel
        GridStatusLampLabel            matlab.ui.control.Label
        GridStatusLamp                 matlab.ui.control.Lamp
        AcquireImagesButton            matlab.ui.control.Button
        filename_ext                   matlab.ui.control.Label
        SystemStatusPanel              matlab.ui.container.Panel
        Camera_status_label            matlab.ui.control.Label
        MagnificationLabel_2           matlab.ui.control.Label
        MicroscopyMethodLabel          matlab.ui.control.Label
        GridSizeLabel_2                matlab.ui.control.Label
        Magnification_status           matlab.ui.control.Label
        Microscopy_status              matlab.ui.control.Label
        Grid_size_status               matlab.ui.control.Label
        Current_camera                 matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        System_status                  matlab.ui.control.Label
        CurrentOperationLabel          matlab.ui.control.Label
        Camera_view                    matlab.ui.control.Button
        AcquisitionTypeLabel_2         matlab.ui.control.Label
        Acquisition_method             matlab.ui.control.Label
        StageControlPanel              matlab.ui.container.Panel
        x_coord_entry                  matlab.ui.control.EditField
        y_coord_entry                  matlab.ui.control.EditField
        text54                         matlab.ui.control.Label
        text54_2                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_3                       matlab.ui.control.Label
        text54_4                       matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        y_coord_text                   matlab.ui.control.Label
        FocusUpButton                  matlab.ui.control.Button
        FocusPosLabel                  matlab.ui.control.Label
        FocusPos                       matlab.ui.control.Label
        FocusDownButton                matlab.ui.control.Button
        FocusPos_2                     matlab.ui.control.Label
        StepSizeEditFieldLabel         matlab.ui.control.Label
        Step_size                      matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        SnapShotPreviewButton          matlab.ui.control.Button
        ImagePreviewPanel              matlab.ui.container.Panel
        Image_Preview                  matlab.ui.control.Image
        FilenameEditFieldLabel         matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        ExpTimemsLabel                 matlab.ui.control.Label
        Exposure_time_2                matlab.ui.control.NumericEditField
        SettingsTab                    matlab.ui.container.Tab
        ImageSettingsPanel             matlab.ui.container.Panel
        MagnificationLabel             matlab.ui.control.Label
        MagnificationDropDown          matlab.ui.control.DropDown
        ExposureTime1Label             matlab.ui.control.Label
        ExposureTime1                  matlab.ui.control.NumericEditField
        msLabel_2                      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        ExposureTime2EditFieldLabel    matlab.ui.control.Label
        ExposureTime2                  matlab.ui.control.NumericEditField
        AcquisitionPanel               matlab.ui.container.Panel
        MicroscopeSetupLabel           matlab.ui.control.Label
        MicroscopeSetupDropDown        matlab.ui.control.DropDown
        AcquisitionTypeLabel           matlab.ui.control.Label
        AcquisitionType                matlab.ui.control.DropDown
        ExposureTimeLabel              matlab.ui.control.Label
        Exposure_time                  matlab.ui.control.NumericEditField
        AutoFocus                      matlab.ui.control.CheckBox
        LinearPolarizersPanel          matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        CurrentposLabel                matlab.ui.control.Label
        Rotate_Detection               matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DetectionEditFieldLabel        matlab.ui.control.Label
        DetectionEditField             matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel     matlab.ui.control.Label
        IlluminationEditField          matlab.ui.control.NumericEditField
        Rotate_Detection_2             matlab.ui.control.Button
        BrowseButton_2                 matlab.ui.control.Button
        SaveDirectoryLabel             matlab.ui.control.Label
        SaveDirectory                  matlab.ui.control.EditField
        GridLayoutPanel                matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        CornersTab                     matlab.ui.container.Tab
        calculate_grid_button          matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        set_UL_coords_button           matlab.ui.control.Button
        set_LR_coords_button           matlab.ui.control.Button
        SingleCornerTab                matlab.ui.container.Tab
        set_grid_button                matlab.ui.control.Button
        Image_3                        matlab.ui.control.Image
        set_up_left_button             matlab.ui.control.Button
        set_low_right_button           matlab.ui.control.Button
        LoadGridButton                 matlab.ui.control.Button
        GridSizeLabel                  matlab.ui.control.Label
        Grid_xentry                    matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        Grid_yentry                    matlab.ui.control.NumericEditField
        SetZFocusButton                matlab.ui.control.Button
        StitchingTypeDropDownLabel     matlab.ui.control.Label
        StitchingType                  matlab.ui.control.DropDown
        Image_Preview_2                matlab.ui.control.Image
        FocusUpButton_2                matlab.ui.control.Button
        FocusPosLabel_2                matlab.ui.control.Label
        FocusPos_3                     matlab.ui.control.Label
        FocusDownButton_2              matlab.ui.control.Button
        mLabel_2                       matlab.ui.control.Label
        StepSizeEditFieldLabel_2       matlab.ui.control.Label
        Step_size_2                    matlab.ui.control.NumericEditField
        ExpTimemsLabel_2               matlab.ui.control.Label
        Exposure_time_3                matlab.ui.control.NumericEditField
        DownButton                     matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        UpButton                       matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SetZFocusforPositionButton     matlab.ui.control.Button
        Label_6                        matlab.ui.control.Label
        UnSelectPositionButton         matlab.ui.control.Button
        SelectPositionButton           matlab.ui.control.Button
        Camera_view_2                  matlab.ui.control.Button
        Camera_status_label_2          matlab.ui.control.Label
        Current_camera_2               matlab.ui.control.Label
        GridSelectionDropDownLabel     matlab.ui.control.Label
        GridSelectionDropDown          matlab.ui.control.DropDown
        SetUpperLeftButton             matlab.ui.control.Button
        SetLowerRightButton            matlab.ui.control.Button
        UpperLeftLabel                 matlab.ui.control.Label
        UpperRightLabel                matlab.ui.control.Label
        UL_Location_lbl                matlab.ui.control.Label
        NALabel                        matlab.ui.control.Label
        SampleParametersPanel          matlab.ui.container.Panel
        SampleThicknessEditFieldLabel  matlab.ui.control.Label
        SampleThickness                matlab.ui.control.NumericEditField
        mLabel_3                       matlab.ui.control.Label
        SampleTypeEditFieldLabel       matlab.ui.control.Label
        SampleTypeEditField            matlab.ui.control.EditField
        mLabel_4                       matlab.ui.control.Label
        SamplethicknessOffsetLabel     matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        ZStackOffsetLabel              matlab.ui.control.Label
        ZStackoffset                   matlab.ui.control.NumericEditField
        ZStackStepSizeLabel            matlab.ui.control.Label
        ZStackStepSize                 matlab.ui.control.NumericEditField
        OpenMicroManagerButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        x_coord_motor=55;
        y_coord_motor=37.5;
        det_degree_settings;
        ill_degree_settings;
        det_RCA_LCA_settings;
        ill_RCA_LCA_settings;
        motor_x;
        motor_y;
        motor_ill;
        motor_det;
        save_folder;
        z_step = 1; % 1 micron
        saved_coordinates = cell(1,1);
        saved_coordinates_string = cell(1,1);
        saved_z_coordinates = zeros(1,3);
        saved_z_coordinates_string = cell(1,1);
        
        zstack_display = zeros(1200,1600,41);
        zstack_fm = zeros(41,1);
        i_zstack = 0;
        
        % FOV in microns calculated from calibration slide for each magnification
        mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
        mags = [4 10 16 20 32 40 64 60 96]';
        pixels_per_100_microns = [54 136 210 270 420 542 840 815 1310]';
        FOV_pixels = [1200; 1600]; % [y, x]
    
        mag_index = 4;
        mag = 20;
        obj_string = '20X';
        
        overlap_for_stitching = 0.25; % percent
        
        stitching_perimeter = zeros(1,2);
        stitching_coordinates = 0;
        rectangular_stitching = 0;
        perimeter_stitching = 0;
        rectangular_stitch_folder = '';
        perimeter_stitch_folder = '';
        ULx = 55;
        ULy = 37.5;
        LRx = 0;
        LRy = 0;
        grid_size = 0;
       
        grid_size_x = 2;
        grid_size_y = 2;
        x_grid_mesh;
        y_grid_mesh;
        x_img_mesh;
        y_img_mesh;
        empty_tile = false;
        z_img_mesh = 0;
        FOV_microns_y;
        FOV_microns_x;
        FOV_microns_stitching_y;
        FOV_microns_stitching_x;
        mmc;
        saved_z_positions;
        z_position;
        exposure_time = 400;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%% add ons for new GUI %%%%%%%%%%%%%%%%%%%
        path_location = '\\ad\eng\users\a\l\algray\My Documents\MATLAB\qBRM\'; % main folder location
        Experimental_file_location = ''; % create experiment folder
        acquisiton_type;
        directory_created = 0;
        bigio_folder;
        directory_counter = 2;
        filename_counter = 2 % Description
        
        correction_factor = 15; %Correction from 10X objective to 40x
        exp_times = [2 5 10 15 32 40 64 60 96]'; % Description
        rect_handler = gobjects(2,2); % create graphics array to store graphing handlers
        grid_select_index = [1,1];
        
        Grid_select_points; % number of selected grid points 
        grid_plane; % intialize array to hold points for plane calculation 
    end
    
    methods (Access = private)
       function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);      
        end
        
        function move_motor(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end
                
        function moveto(app, motor, position)
            try
            workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
            motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
            motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed');
                motor.Home(100000);
            end
        end
        
        function ask_to_insert_16x_lens(app)
            % Ask user to insert 1.6x lens into detection path
            d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
        end
        function ask_user_mmc(app, display_text)
            if(strcmp(app.camera_status.Text, 'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    display_text);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
        end
        

        function plot_grid(app, x_min, x_max, y_min, y_max)
            % Plotting
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x_img_mesh,app.y_img_mesh,'.','Color','red');
            
            plot(app.UIAxes,app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
           
            plot(app.UIAxes,app.x_grid_mesh',app.y_grid_mesh','Color','blue');
            
            ax = gca;
            ax.XLim = [x_min-1.5 x_max+1.5];
            ax.YLim = [y_min-1.5 y_max+1.5];
            ax.YDir = 'reverse';
            ax.XDir = 'reverse';
        end
        
        function load_camera_with_mmc(app)
            try
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
        
            app.Current_camera.Text = 'MATLAB';
            catch ME
                message = 'Error Loading Camera, Restart Camera and Try again';
                uiwait(warndlg(message));
            end 
                
        end
        
        function input_save_filename(app)
            %title = 'Enter the save name for this image set.';
            %user_answer = inputdlg('Enter the save name for this image set:',...
            %    title,[1 length(title)+30]);
            %save_string = user_answer{1,1};
            %generate_file_extension(app);
            
            save_string = ['\' num2str(app.FileName.Value)];
            cd(app.save_folder);
            mkdir(save_string);
            app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.rectangular_stitch_folder);
        end
        
        % Save multi-exposure images for current xy location
        function save_multi_exp_images(app, exp_times, img_index, img, z_focus)
            for i = 1:length(exp_times)
                cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);

                if(img_index < 10)
                    new_filename = sprintf('img_00%d.tif',img_index);
                elseif(img_index > 99)
                    new_filename = sprintf('img_%d.tif',img_index);
                else
                    new_filename = sprintf('img_0%d.tif',img_index);
                end
                % Save images with indexing for ImageJ Stitching plug-in
                imwrite(uint16(img{y,x}(:,:,i)),new_filename);
    
                % Update saved Z-plane information
                save('z_focus.mat','z_focus');
            end
        end
        
        %disconnects all motors
        function disconnect_all_motors(app)
            app.motor_x.StopPolling();
            app.motor_x.Disconnect();
            
            app.motor_y.StopPolling();
            app.motor_y.Disconnect();
            
            app.motor_ill.StopPolling();
            app.motor_ill.Disconnect();
            
            app.motor_det.StopPolling();
            app.motor_det.Disconnect();
        end
        
        %opens window to choose magnification
        function [mag, mag_index] = choose_magnification(app)
            [app.mag_index,~] = listdlg('PromptString','Choose the magnification for stitching acquisition:','SelectionMode','Single',...
                'Name','Magnification Selection','ListSize',[300 200],'ListString',app.mag_list);
            mag = app.mags(app.mag_index,1);
            if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                app.obj_string = app.mag_list{1,app.mag_index};
            else
                app.obj_string = app.mag_list{1,app.mag_index - 1};
            
                % Ask user to insert 1.6x lens into detection path
                d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            mag_index = app.mag_index;
        end   
        
        %find z position, so the sample is in focus
        function [img, current_z] = autofocusing(app, correction_factor, exp_time, objective, z_focus_last)
                % Variable correction_factor is used to correct for the slight displacement
                % in z between images collected with the 10X and 40X objectives
                app.mmc.setConfig('Objective', objective);
                current_z = app.mmc.getPosition;
                
                done = 0;
                fine_adj = 0;
                n = 0;
                
                disp('Finding best focus...');
                while(done == 0)
                    n = n + 1;
                    fprintf('Iteration #%d  ',n);
                    if(fine_adj == 0)
                        increment = 50;
                        fprintf('(coarse) \n');
                    else
                        increment = 5;
                        fprintf('(fine) \n');
                    end
            
                    if(n == 1)
                        if(strcmp(z_focus_last,'current position') == 1)
                            current_z = app.mmc.getPosition;
                            pause(0.25);
                        elseif (strcmp(z_focus_last,'nothing') == 1)
                            %nothing
                        else
                            current_z = z_focus_last;
                        end
                    elseif(n == 2)
                        direction = 1; % positive
                        current_z = current_z + direction*increment; % Move objective block up
                    else
                        % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                        if(fm(n - 1,1) > fm(n - 2,1))
                            current_z = current_z + direction*increment;
                        else
                            current_z = current_z - direction*increment;
                        end
                        
                    end
            
            
                    app.mmc.setPosition(current_z);
                    pause(0.25);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
            
                    % After n = 3 iteration, the direction the z-stage needs to move has been determined
                    if(n > 3)
                        if(fm(n,1) < fm(n-1,1))
                            current_z = current_z - direction*increment;
                            if(fine_adj == 0)
                                fine_adj = 1;
                                n = 0;
                            else
                                done = 1;
                            end
                        end
                    end
            
                end
  
                current_z = round(app.mmc.getPosition - correction_factor); % Round to nearest integer
        end
        
        function Disable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'off';
            app.SnapShotPreviewButton.Enable = 'off';
            app.y_coord_entry.Enable = 'off';
            app.x_coord_entry.Enable = 'off';
            app.MoveXYmotorButton.Enable = 'off';
            app.FileName.Enable = 'off';
            app.FocusUpButton.Enable = 'off';
            app.FocusDownButton.Enable = 'off';
            app.Camera_view.Enable = 'off';
            app.Step_size.Enable = 'off';
            app.Exposure_time_2.Enable = 'off';
        end
        
        function Enable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'on';
            app.SnapShotPreviewButton.Enable = 'on';
            app.y_coord_entry.Enable = 'on';
            app.x_coord_entry.Enable = 'on';
            app.MoveXYmotorButton.Enable = 'on';
            app.FileName.Enable = 'on';
            app.FocusUpButton.Enable = 'on';
            app.FocusDownButton.Enable = 'on';
            app.Camera_view.Enable = 'on';
            app.Step_size.Enable = 'on';
            app.Exposure_time_2.Enable = 'on';
        end
        
        function acquire_preview_image(app)
            img = snap_image(app.Exposure_time.Value,app.mmc);
            app.System_status.Text = 'Acquiring Image';
            Disable_btn_functionality(app);
            app.Image_Preview.ImageSource = repmat(img, 1, 1, 3);
            app.Image_Preview_2.ImageSource = repmat(img, 1, 1, 3);
            app.System_status.Text = 'Standby';
            Enable_btn_functionality(app);
        end
        
        %take entry box input and create save directory (or CD to whatever
        %is already saved
        function create_save_directory(app)
            new_folder_name = app.SaveDirectory.Value;
            cd([app.bigio_folder,'\Images']);
            if ~exist(new_folder_name, 'dir')
                mkdir(new_folder_name);
            end
            app.save_folder = strcat(app.bigio_folder,'\Images\',new_folder_name);
            cd([app.bigio_folder,'\Images\',new_folder_name]);
            app.directory_created = 1;
            
            % try create the file folder
            filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value),...
                'ms_mag', app.MagnificationDropDown.Value, '\'));
            if ~exist(filedir, 'dir')
                mkdir(filedir);
                cd([app.save_folder, '\', filedir]);
                app.FileName.Value = strcat(app.FileName.Value, '_', num2str(app.directory_counter)); % update filename so it doesnt run into errors
            else
                filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag',...
                    app.MagnificationDropDown.Value, '_', num2str(app.directory_counter), '\'));
                mkdir(filedir);
                cd([app.save_folder, '\', filedir]);
                app.filename_counter = app.filename_counter + 1;
            end
            app.save_folder = [app.save_folder, '\', filedir];
        end
        
        function write_image_format(app, image, filename, format)
            imwrite(image, filename, format);
        end
        
        function update_grid(app)
            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;
            center_position_actualx = zeros(1,x);
            center_position_actualy = zeros(1,y);
            
            cla(app.UIAxes,'reset') %reset axis
            
            %%%%%%%%%%% acutally create grid %%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x_ind = 1:x %start at top left index 
                for y_ind = 1:y
                    posx = app.ULx - (x_ind)*app.FOV_microns_stitching_x(app.mag_index)/1000; % x  retangle corner (plotting)
                    posy = app.ULy + (y_ind)*app.FOV_microns_stitching_y(app.mag_index)/1000;% y  retangle corner (plotting)
                    center_posx = posx + app.FOV_microns_stitching_x(app.mag_index)/2000; % x retangle center (plotting)
                    center_posy = posy + app.FOV_microns_stitching_y(app.mag_index)/2000; % y retangle center (plotting)
                    
                    %%% calc the (x,y) positions for imaging (actual)
                    center_position_actualx(x_ind) = posx - app.FOV_microns_x(app.mag_index)/2000; % The actual position for x
                    center_position_actualy(y_ind) = posy + app.FOV_microns_y(app.mag_index)/2000; % The actual position for y
                    
                    app.rect_handler(y_ind,x_ind) = rectangle(app.UIAxes, 'Position', ...
                    [posx posy app.FOV_microns_stitching_x(app.mag_index)/1000 app.FOV_microns_stitching_y(app.mag_index)/1000], 'FaceColor', 'White');
                    hold(app.UIAxes,'on');
                    app.UIAxes.XDir = 'Reverse';
                    app.UIAxes.YDir = 'Reverse';
                    plot(app.UIAxes, center_posx, center_posy, 'r*');
                end
            end
            app.grid_select_index = [1,1];   
            [app.x_img_mesh, app.y_img_mesh] = meshgrid(center_position_actualx, center_position_actualy);
            app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
        end
        
        function z_plane = calc_z_plane(app)
            disp('need all the functionality rewritten')
            P1 = app.grid_plane(:,:,1); % extract data points to define plane
            P2 = app.grid_plane(:,:,2);
            P3 = app.grid_plane(:,:,3);
            N = cross(P1-P2,P1-P3); % find normal vector
            N = N./norm(N); % normalaze the value
            A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
            x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
            z_plane = (-A.*(app.x_img_mesh-x0)-B.*(app.y_img_mesh-y0)+C.*z0)./C; % find z values with plane equation
        end
        
        function reset_grid_parameters(app)
            app.Grid_select_points = 1;
            app.grid_plane;
            app.SelectPositionButton.Enable = 'on';
            app.UnSelectPositionButton.Enable = 'off';
        end  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Disable_btn_functionality(app);
            app.System_status.Text = 'Iniatializing...';
            
            % Choose default command line output for control_new_offline_copy
            current_folder = pwd;
            addpath(current_folder);
            [~, bigio_index_end] = regexp(current_folder,'_Bigio');
            bigio_folder = current_folder(1:bigio_index_end);
            app.bigio_folder = bigio_folder;
            
            % Include path to MATLAB folder
            matlab_folder = [bigio_folder,'\MATLAB'];
            addpath(matlab_folder);
            
            % Include path to functions
            addpath([matlab_folder,'\functions']);
            % Load stages and settings
            for nnn = 1:1
                calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
                addpath(calibration_path{1,1});
                ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
                det_degree_settings = load('det_5_degree_settings.mat'); %detection
                calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
                addpath(calibration_path{2,1});
                ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
                det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
                addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
                % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local
            
                field_names = fieldnames(det_degree_settings);
                app.det_degree_settings = det_degree_settings.(field_names{1,1});
                field_names = fieldnames(ill_degree_settings);
                app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
                field_names = fieldnames(det_RCA_LCA_settings);
                app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
                field_names = fieldnames(ill_RCA_LCA_settings);
                app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
                clearvars field_names
            end

            %app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
            %app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1)); 
            
            % Create time-stamped save folder each time an imaging session is started
            t = clock;
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
                t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            cd([bigio_folder,'\Images']);
            %mkdir(new_folder_name);
            %app.save_folder = [bigio_folder,'\Images\',new_folder_name];
            %app.save_dir_user_entry.Value = app.save_folder;
            %cd(app.save_folder);
            
            max_slider_val = 36;
            min_slider_val = 1;
            slider_steps1 = 1/(max_slider_val - min_slider_val);
            slider_steps2 = 3/(max_slider_val - min_slider_val);
            
            %app.mmc.setConfig('Objective', app.obj_string); % set magnification
            %!C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            
            app.FOV_microns_y = 100*app.FOV_pixels(1,1)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.FOV_pixels(2,1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
            
            % set grid size *(Default = [2,2])
            app.grid_size = [app.Grid_yentry.Value, app.Grid_xentry.Value];
            app.Grid_select_points = 1;
            %%%%%%%%%%% after Old GUI %%%%%%%%%%%%%%%%%%%%%
            current_folder = pwd;
            current_folder = strcat(current_folder, "\");
            %addpath(current_folder);
            %[~, bigio_index_end] = regexp(current_folder,'_Bigio');
            %bigio_folder = current_folder(1:bigio_index_end);
            %cd([bigio_folder,'\Images']);
            
            t = clock;
            
            % can replace with an experimental file
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            app.FileName.Value = new_folder_name; % update default file name
            app.SaveDirectory.Value = new_folder_name;
            
            
            % load grid into window axis
            %plot_grid(app, x_min, x_max, y_min, y_max)
          
            
            %%%% set all default values
            app.MagnificationDropDown.Value = app.mag_list{1,app.mag_index};
            app.Microscopy_status.Text = app.MicroscopeSetupDropDown.Value;
            app.Magnification_status.Text = app.MagnificationDropDown.Value;
            grid_1 = strcat(string(app.grid_size_x),'x');
            grid_string = strcat(grid_1,string(app.grid_size_y));
            app.Grid_size_status.Text = grid_string;
            
            %% create grid onto axis 
            app.System_status.Text = 'Creating Grid';
            update_grid(app);
            
            % load camera and motor last 
            load_camera_with_mmc(app);
            
            % load in the current focus position
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            app.System_status.Text = 'Initializing Stage...';
            
            % set up proper objective
            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            
            % initialize Motor Last
            SN_ill = '55001048';
            SN_det = '55001045';
            SN_xy_controller = '73109054';
        
            %create motor objects and home devices
            [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
            app.motor_ill = rotat_stage_init(SN_ill);
            app.motor_det = rotat_stage_init(SN_det);
            % Start in LCP-RCA configuration
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
            
            Enable_btn_functionality(app);
            app.System_status.Text = 'Standby';
            disp('Initialization finished');
        end

        % Button pushed function: AcquireImagesButton
        function AcquireImagesButtonPushed(app, event)
            type = app.AcquisitionType.Value;
            
            switch type
                case 'Single Image'
                    app.System_status.Text = 'Acquiring Image';
                    Disable_btn_functionality(app);
                    img = snap_image(app.Exposure_time.Value,app.mmc);
                    app.Image_Preview.ImageSource = repmat(img, 1, 1, 3);
                    app.Image_Preview_2.ImageSource = repmat(img, 1, 1, 3);
                    
                    %% create save directory
                    if (app.directory_created ~= 1)
                        create_save_directory(app)
                    end
                    
                    % actually save the image as both a tif and a PNG
                    filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                    
                    % write to file
                    imwrite(img, filename, 'tiff');
                    
                    Enable_btn_functionality(app);
                    
                    app.System_status.Text = 'Standby';
                    %potentially write as a png instead of tiff
                    %write_image_format(app, img, filename, 'png');
                    
                    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Z-Stack Imaging                               %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Single Z-Stack'
                    disp('Case 2')
                    app.System_status.Text = 'Creating Save Directory';
                    Disable_btn_functionality(app);
                    %% create save directory
                    if (app.directory_created ~= 1)
                        create_save_directory(app)
                    end
                    
                    % check if filename already exists
                    filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                    if isfile(filename)
                        name = strcat(app.FileName.Value, '(', num2str(app.filename_counter), ')');
                        app.FileName.Value = name;
                        filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                        app.filename_counter = app.filename_counter + 1;
                    end
                    
                    % get current position 
                    current_z = app.mmc.getPosition;
                    %% autofocus if it is checked
                    if app.AutoFocus.Value
                        app.System_status.Text = 'Auto-Focusing';
                        [img, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, '10X', 'nothing');
                    end
                    
                    app.System_status.Text = 'Acquring Z-Stack';
                    % go to proper focusing with the correct objective and
                    % give preview
                    app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                    img = snap_image(app.Exposure_time.Value,app.mmc);
                    app.Image_Preview.ImageSource = repmat(img, 1, 1, 3);
                    app.Image_Preview_2.ImageSource = repmat(img, 1, 1, 3);

                    thickness = app.SampleThickness.Value/2;
                    offset = app.ZStackoffset.Value;
                    step = app.ZStackStepSize.Value;
                    
                    % create Z positions for Z_stack 
                    z_positions = (current_z-thickness-offset):(step):...
                    (current_z + thickness + offset);
                
                    num_z_steps = length(z_positions);
                    zstack = zeros([app.FOV_pixels',num_z_steps+1]); % space for Z stack images
                    
                    for i = 1:length(z_positions)
                        app.mmc.setPosition(z_positions(i));
                        zstack(:,:,i) = double(snap_image(app.Exposure_time.Value,app.mmc));
                    end
                    
                    fig3 = figure('Position',[1000 200 800 600],'Name','Z-Stack');
                    imshow3D(zstack);
                    
                    % write z-stack to file location 
                    for i = 1:size(zstack,3)
                        imwrite(uint16(zstack(:,:,i)),filename,'tif','WriteMode','append');
                    end
                    
                    % enable all buttons back after acqusition
                    Enable_btn_functionality(app);
                    app.mmc.setPosition(current_z); % reset the focus back to the center plane
                    
                    app.System_status.Text = 'Standby';
                    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Sitching Imaging                              %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Stitching'
                    disp('Case 3')
                    if app.grid_select_index < 3
                        app.System_status.Text = 'Creating Directories';
                        
                        create_save_directory(app);
                        
                        % create exposure time files
                        cd(app.save_folder);
                        exp_folder_1 = sprintf('exp%dms',app.ExposureTime1.Value);
                        exp_folder_2 = sprintf('exp%dms',app.ExposureTime2.Value);
                        mkdir(exp_folder_1);
                        mkdir(exp_folder_2);
                        
                        z_size = 1; % the number of planes to take
                        
                        % if it is multiplane then create the z values
                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                            [num_images_row,num_images_col] = size(app.x_img_mesh);
                            z_mesh = zeros(num_images_row,num_images_col,(app.SampleThickness.Value + app.ZStackoffset.Value));
                            z_mesh(:,:,1) = calc_z_plane(app) - ((app.SampleThickness.Value/2)+app.ZStackoffset.Value)*ones(num_images_row,num_images_col); % get bottom plane
                           
                            for z = 1:(app.SampleThickness.Value)+app.ZStackoffset.Value
                                z_mesh(:,:, z+1) =  z_mesh(:, :, z) + ones(num_images_row,num_images_col);
                            end
                        end
                        
                        y = 0;
                        index = 0;
                        app.System_status.Text = 'Stitching Acqusition';
                        for y_idx = 1:size(app.x_img_mesh,1)
                            y = y + 1;
                            for x_idx = 1:size(app.x_img_mesh,2)
                                if(mod(y,2) == 1)
                                    % For odd number rows, moving left to right
                                    x = x_idx;
                                else
                                    % For even number rows, moving right to left
                                    x = size(app.x_img_mesh,2) - x_idx + 1;
                                end
                                %move to the preset point
                                move_motor(app,app.x_img_mesh(y,x), app.y_img_mesh(y,x));
                                pause(0.1);
                
                                % Move objective to z_focus value for the current tile
                                app.mmc.setPosition(app.z_img_mesh(y,x));
                                pause(0.25);
                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                    img = cell(2,1);
                                    img{1,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                    img{2,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                    for z = 1:size(z_mesh,3)
                                        app.mmc.setPosition(z_mesh(y,x,z));
                                        pause(0.2);
                                        img{1,1}(:,:,z) = snap_image(app.ExposureTime1.Value,app.mmc);
                                        img{2,1}(:,:,z) = snap_image(app.ExposureTime2.Value,app.mmc);
                                    end
                                else
                                    img_exp_1 = snap_image(app.ExposureTime1.Value, app.mmc);
                                    img_exp_2 = snap_image(app.ExposureTime2.Value, app.mmc);
                                end 
                                index = index + 1;
                                
                                % Save multi-exposure images for current xy location
                                %cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                    
                                if(index < 10)
                                    new_filename = sprintf('img_00%d.tif',index);
                                elseif(index > 99)
                                    new_filename = sprintf('img_%d.tif',index);
                                else
                                    new_filename = sprintf('img_0%d.tif',index);
                                end
                                % save as either multiplane or single
                                % plane
                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                    cd([app.save_folder, '\', exp_folder_1]);
                                    for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                        imwrite(uint16(img{1,1}(:,:,z)),[new_filename,'.tif'],'tif','WriteMode','append');
                                    end
                                    cd([app.save_folder, '\', exp_folder_2]);
                                    for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                        imwrite(uint16(img{2,1}(:,:,z)),[new_filename,'.tif'],'tif','WriteMode','append');
                                    end
                                else 
                                    % Save images with indexing for ImageJ Stitching plug-in
                                    cd([app.save_folder, '\', exp_folder_1]);
                                    imwrite(uint16(img_exp_1),new_filename);
                                    cd([app.save_folder, '\', exp_folder_2]);
                                    imwrite(uint16(img_exp_2),new_filename);
                                end
                            end
                                end 
                            app.System_status.Text = 'Standby';
                        else 
                            disp('Grid has not been initialized')
                    end
                        
                    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Widefield Acqusition                          %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Widefield (4x)'
                    disp('Case 4')
                    
            end
        end

        % Size changed function: SingleCornerTab
        function BrowseButtonPushed(app, event)
             user_selection = uigetdir();
             
             disp(user_selection);
             
             app.Label_5.Text = strcat('File Location: ', user_selection); % update label for experiment location 
             myPath = 'C:\work\matlab';
               C = strsplit(myPath,'\')
             
        end

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            value = app.MagnificationDropDown.Value;
            app.Magnification_status.Text = value;
            app.mag_index = find(app.mags == str2num(erase(convertCharsToStrings(value),"X"))); %Find index from the Mag character
            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            app.Exposure_time.Value = app.exp_times(app.mag_index);
            app.Exposure_time_2.Value = app.exp_times(app.mag_index);
            
            update_grid(app);
            % have a little check in case the buttons are accidently
            % disabled 
            Enable_btn_functionality(app);
            
        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            move_motor(app,app.x_coord_motor, app.y_coord_motor);
        end

        % Button pushed function: SnapShotPreviewButton
        function SnapShotPreviewButtonPushed(app, event)
            acquire_preview_image(app);
           
        end

        % Button pushed function: Camera_view, Camera_view_2
        function Camera_viewPushed(app, event)
            if (strcmp(app.Current_camera.Text, 'MATLAB'))
                app.System_status.Text = 'Moving to Micro-Manager';
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';
                app.SnapShotPreviewButton.Enable = 'off';
                app.AcquireImagesButton.Enable = 'off';
                % Set camera to Eye peice on microscope
                %app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
                app.mmc.reset();
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
                app.Current_camera.Text = 'Micro-Manager Eye-Piece';
                app.Current_camera_2.Text = 'Micro-Manager Eye-Piece';
                app.System_status.Text = 'Standby';
            else 
                app.System_status.Text = 'Loading Camera to MATLAB';
                load_camera_with_mmc(app);
                app.mmc.setConfig('lightpath','Side Port'); % change light path to look at eyepiece (dark field)
                formatSpec = '%.1f';
                app.z_position = app.mmc.getPosition;
                app.FocusPos.Text = num2str(app.z_position, formatSpec);
                app.Current_camera.Text = 'MATLAB';
                app.Current_camera_2.Text = 'MATLAB';
                app.System_status.Text = 'Standby';
                app.FocusDownButton.Enable = 'on';
                app.FocusUpButton.Enable = 'on';
                app.SnapShotPreviewButton.Enable = 'on';
                app.AcquireImagesButton.Enable = 'on';  
            end
        end

        % Button pushed function: FocusUpButton, FocusUpButton_2
        function FocusUpButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position + app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            % acquire Image and update
            acquire_preview_image(app);
        end

        % Button pushed function: FocusDownButton, FocusDownButton_2
        function FocusDownButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position - app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            %acquire image for preview
            acquire_preview_image(app);
            
        end

        % Button pushed function: SetZFocusButton
        function SetZFocusButtonPushed(app, event)
             if isempty(app.x_img_mesh)
                % error
                d = dialog('Position',[300 300 400 100],'Name','Grid Information is Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Imaging coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            else
                % Plotting
                fig1 = figure('Position',[300 50 1200 900]);
                hold on;
                plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
                plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
                plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
                x_min = min(app.x_img_mesh(1,:));
                x_max = max(app.x_img_mesh(1,:));
                y_min = min(app.y_img_mesh(:,1));
                y_max = max(app.y_img_mesh(:,1));
                xlim([x_min-1.5 x_max+1.5]);
                ylim([y_min-1.5 y_max+1.5]);
                ax = gca;
                ax.YDir = 'reverse';
                ax.XDir = 'reverse';
                xlabel('X coordinates (mm)');
                ylabel('Y coordinates (mm)');
                title('Select Upper Left sample tile for focusing.'); % Start with upper left
                
                % Ask user to switch to correct objective and view with Micro-Manager
                d = dialog('Position',[300 300 400 100],'Name','Use Micro-Manager for Live View');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    ['Move ',app.obj_string,' into place and view sample with Micro-Manager.']);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
                
                % Manually select tiles, go to selected coordinates, and ask user to designate if empty
                z_focus_corners = zeros(3,1);
                corner_indices = zeros(3,2);
                fprintf('First, select Upper Left sample tile for focusing.\n');
                count = 0;
                while count < 3
                    %read mouse position
                    [x,y] = ginput(1);
                    %end function by closing interface
                    if(isempty(x) == true && isempty(y) == true)
                        break;
                    else
                        % calculating based on where you click what lines of rectangle to
                        % highlight
                        [~,x_ind] = min(abs(x*ones(1,length(app.x_img_mesh(1,:))) - app.x_img_mesh(1,:)));
                        [~,y_ind] = min(abs(y*ones(length(app.y_img_mesh(:,1)),1) - app.y_img_mesh(:,1)));
                        closed_rect = [app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind)];
                        tile_highlight1 = plot(closed_rect(:,1),closed_rect(:,2),'Color','red','LineWidth',5);
                
                        % Go to selected XY coordinates
                        y_coord = app.y_img_mesh(y_ind,1);
                        x_coord = app.x_img_mesh(1,x_ind);
                        move_motor(app, x_coord, y_coord);
                
                        % Ask user to confirm that they would like to use this tile for determining objective focus for the current corner
                        use_tile_ans = questdlg('Use this tile for determining Z-focus position in this corner of the sample?',...
                            'Choose corner tile for determining Z-focus','Yes','No - choose a new tile','Yes');
                        if(strcmp(use_tile_ans,'Yes') == 1)
                            % Tell user to close Micro-Manager to get objective Z-position
                            d = dialog('Position',[300 300 400 100],'Name','Bring sample into focus and close Micro-Manager to get objective Z-position');
                            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                                'Close Micro-Manager before clicking "Continue".');
                            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                                'Callback','delete(gcf)');
                            %waits until you click continue
                            uiwait(d);
                
                            % Load microscope hardware through Micro-Manager core and get objective position
                            import mmcorej.*;
                            app.mmc = CMMCore;
                            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
                            app.camera_status.Text = 'MATLAB';
                
                            count = count + 1;
                            z_focus_corners(count,1) = app.mmc.getPosition;
                            corner_indices(count,:) = [y_ind,x_ind];
                            app.mmc.reset();
                            if(count < 3)
                                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                                app.camera_status.Text = 'Micro-Manager';
                            end
                
                            % Display recorded z-focus and indicate next corner to focus
                            if(count == 1)
                                P1 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Upper Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Upper Right sample tile for focusing.'); % UL --> UR
                            elseif(count == 2)
                                P2 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Right Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Left sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Left sample tile for focusing.'); % UR --> LL
                            elseif(count == 3)
                                P3 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Lower Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Right sample tile for focusing.'); % LL --> LR
                            end
                            delete(tile_highlight1);
                            patch(closed_rect(:,1),closed_rect(:,2),'green');
                
                        elseif(strcmp(use_tile_ans,'No - choose a new tile') == 1)
                            disp('Choose a new tile to use for focusing.');
                        end
                    end
                end
                
                % Calculate Z-focus values across the entire set of tiles
                % use 3 selected points on the plane to find plane equation
                N = cross(P1-P2,P1-P3); % find normal vector
                N = N./norm(N); % normalaze the value
                A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
                x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
                x = app.x_img_mesh; %set values for x,y
                y = app.y_img_mesh;
                z_img_mesh = (-A.*(x-x0)-B.*(y-y0)+C.*z0)./C; % find z values with plane equation
                
                app.z_img_mesh = z_img_mesh;
                cd(app.rectangular_stitch_folder);
                
                save('image_z_coords.mat','z_img_mesh','-v7.3');
                disp('Saved Z-focus values to image_z_coords.mat');
                close(fig1);
            end

        end

        % Button pushed function: set_up_left_button
        function set_up_left_buttonButtonPushed(app, event)
             % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);

            if(all([app.ULx, app.ULy, app.grid_size]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;
            
                % Choose magnification
                %[mag, app.mag_index] = choose_magnification(app);
                mag = app.mag;
                % Compute actual xy locations for rectangular grid acquisition
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                x_max = app.ULx;
                x_step =app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_x = app.grid_size(1,2);
                
                y_min = app.ULy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_y = app.grid_size(1,1);
                
                x_min = x_max-x_step*(grid_size_x-1); % minimum coordinate for x axis
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min - x_step/2):x_step:(x_max + x_step/2); % Step is divided by 2 to center frame over boundary
            
                y_max = y_min+y_step*(grid_size_y-1); % maximum coordinate for y axis
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);
                
                %grid plotting
                plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);
                
                %save coordinates for opposite corner
                app.LRx = x_min;
                app.LRy = y_max;          
                %app.LRx_coord.Text = num2str(app.LRx);
                %app.LRy_coord.Text = num2str(app.LRy);
                
                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                %app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
            % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
        end

        % Value changed function: Grid_yentry
        function Grid_yentryValueChanged(app, event)
                app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
                reset_grid_parameters(app)
                update_grid(app)
                
                
        end

        % Value changed function: Grid_xentry
        function Grid_xentryValueChanged(app, event)
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            reset_grid_parameters(app);
            update_grid(app);
            
            
        end

        % Value changed function: Exposure_time
        function Exposure_timeValueChanged(app, event)
            value = app.Exposure_time.Value;
            app.Exposure_time_2.Value = value;
            
            % acquire Preview image whenever the Exposure time changes
            acquire_preview_image(app)
            
        end

        % Value changed function: Exposure_time_2
        function Exposure_time_2ValueChanged(app, event)
            value = app.Exposure_time_2.Value;
            app.Exposure_time.Value = value;
            
            % acquire Preview image whenever the Exposure time changes
            acquire_preview_image(app)
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            selpath = uigetdir('C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\Images','Get Save Directory');
            C = strsplit(selpath,'\');
            selpath = C{:,end};
            
            if ~isequal(selpath,0) % if a directory is selected
                app.SaveDirectory.Value = selpath;
                
            end
        end

        % Value changed function: AcquisitionType
        function AcquisitionTypeValueChanged(app, event)
            app.Acquisition_method.Text = app.AcquisitionType.Value;
            if strcmp(app.Acquisition_method.Text, 'Stitching')
                if app.grid_select_index <4
                    app.GridStatusLamp.Color = 'red';
                end 
            end
        end

        % Button pushed function: SetUpperLeftButton
        function SetUpperLeftButtonPushed(app, event)
            disp('Set Upper Left Needed')
            
             % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);
            app.UL_Location_lbl.Text = strcat('(', num2str(app.ULx), ',', num2str(app.ULy), ')');
            reset_grid_parameters(app);
            
            update_grid(app)
        end

        % Button pushed function: SetLowerRightButton
        function SetLowerRightButtonPushed(app, event)
            disp('Set Lower Right Needed')
            reset_grid_parameters(app);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if (app.grid_select_index(1) >1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if (app.grid_select_index(2) < app.Grid_yentry.Value)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if (app.grid_select_index(2) > 1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if (app.grid_select_index(1) < app.Grid_xentry.Value)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            app.x_img_mesh
            app.y_img_mesh
            switch key
                case 'uparrow'
                    if (app.grid_select_index(2) < app.Grid_yentry.Value)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'downarrow'
                    if (app.grid_select_index(2) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'rightarrow'
                    if (app.grid_select_index(1) < app.Grid_xentry.Value) 
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'leftarrow'
                    if (app.grid_select_index(1) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
            end
        end

        % Button pushed function: SelectPositionButton
        function SelectPositionButtonPushed(app, event)
            
            if (app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor == [0 1 0])
                message = 'Location already Selected for Stitching';
                uiwait(warndlg(message));
            else
                % get all positions and save to a global Plane var to calculate
                % plane later on
                z_focus = app.mmc.getPosition;
                [x_pos, y_pos] = read_motor_position(app);
                
                if app.AutoFocus.Value
                    app.System_status.Text = 'Auto-Focusing';
                    [img, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, app.MagnificiationDropDown.Value, 'nothing');
                end
               
                app.grid_plane(:,:,app.Grid_select_points) = [x_pos, y_pos, z_focus];
                app.Grid_select_points = app.Grid_select_points + 1;
                app.UnSelectPositionButton.Enable = 'on';
                
                app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor = 'Green';
                
                if app.Grid_select_points >3
                    app.SelectPositionButton.Enable = 'off';
                    z_plane = calc_z_plane(app);
                    app.z_img_mesh = z_plane;
                    app.GridStatusLamp.Color = 'Green';
                    
                end
            end     
        end

        % Button pushed function: UnSelectPositionButton
        function UnSelectPositionButtonPushed(app, event)
            if (app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor == [0 1 0])
                app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor = 'Yellow';
                
                if app.Grid_select_points >1
                    app.Grid_select_points = app.Grid_select_points - 1;
                end
                
                if app.Grid_select_points < 4
                    app.SelectPositionButton.Enable = 'on';
                end
            else
                message = 'Location is not currently Selected for Stitching';
                uiwait(warndlg(message));
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.mmc.reset()
                disp('Camera Disconnected')
            catch 
                disp('Could not reset camera')
            end
            try
                disconnect_all_motors(app)
                disp('Motors Disconnected')
            catch 
                disp('Couldnt Disconnect Motor')
            end
            
            delete(app)
            
        end

        % Button pushed function: OpenMicroManagerButton
        function OpenMicroManagerButtonPushed(app, event)
            try
                app.System_status.Text = 'Moving to Micro-Manager';
                app.mmc.reset();
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';
                app.SnapShotPreviewButton.Enable = 'off';
                app.AcquireImagesButton.Enable = 'off';
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
            catch 
                disp('MicroManager is already Loaded')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.AcquisitionStatusPanel = uipanel(app.AcquisitionTab);
            app.AcquisitionStatusPanel.Title = 'Acquisition Status';
            app.GridStatusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel.Text = 'Grid Status';
            app.GridStatusLamp = uilamp(app.AcquisitionStatusPanel);
            app.AcquireImagesButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireImagesButtonPushed, true);
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.filename_ext = uilabel(app.AcquisitionTab);
            app.filename_ext.Text = '_exposure-time_magnification';
            app.SystemStatusPanel = uipanel(app.AcquisitionTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.Camera_status_label = uilabel(app.SystemStatusPanel);
            app.Camera_status_label.Text = 'Camera Status: ';
            app.MagnificationLabel_2 = uilabel(app.SystemStatusPanel);
            app.MagnificationLabel_2.Text = 'Magnification:';
            app.MicroscopyMethodLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopyMethodLabel.Text = 'Microscopy Method: ';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Magnification_status = uilabel(app.SystemStatusPanel);
            app.Magnification_status.Text = '20x';
            app.Microscopy_status = uilabel(app.SystemStatusPanel);
            app.Microscopy_status.Text = 'CCP-BRM';
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status.Text = '2x2';
            app.Current_camera = uilabel(app.SystemStatusPanel);
            app.Current_camera.Text = 'Micromanager';
            app.Label_5 = uilabel(app.SystemStatusPanel);
            app.Label_5.Text = '--------------------------------------------------------------------------------------';
            app.System_status = uilabel(app.SystemStatusPanel);
            app.System_status.Text = 'Standby';
            app.CurrentOperationLabel = uilabel(app.SystemStatusPanel);
            app.CurrentOperationLabel.Text = 'Current Operation:';
            app.Camera_view = uibutton(app.SystemStatusPanel, 'push');
            app.Camera_view.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view.Text = 'Camera View';
            app.AcquisitionTypeLabel_2 = uilabel(app.SystemStatusPanel);
            app.AcquisitionTypeLabel_2.Text = 'Acquisition Type:';
            app.Acquisition_method = uilabel(app.SystemStatusPanel);
            app.Acquisition_method.Text = 'Single Image';
            app.StageControlPanel = uipanel(app.AcquisitionTab);
            app.StageControlPanel.Title = 'Stage Control';
            app.x_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.StageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.StageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.MoveXYmotorButton = uibutton(app.StageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_3 = uilabel(app.StageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.StageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.StageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.StageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.FocusUpButton = uibutton(app.StageControlPanel, 'push');
            app.FocusUpButton.ButtonPushedFcn = createCallbackFcn(app, @FocusUpButtonPushed, true);
            app.FocusUpButton.Text = 'Focus Up';
            app.FocusPosLabel = uilabel(app.StageControlPanel);
            app.FocusPosLabel.Text = 'Focus-Pos';
            app.FocusPos = uilabel(app.StageControlPanel);
            app.FocusPos.Text = 'NaN';
            app.FocusDownButton = uibutton(app.StageControlPanel, 'push');
            app.FocusDownButton.ButtonPushedFcn = createCallbackFcn(app, @FocusDownButtonPushed, true);
            app.FocusDownButton.Text = 'Focus Down';
            app.FocusPos_2 = uilabel(app.StageControlPanel);
            app.FocusPos_2.Text = 'NA';
            app.StepSizeEditFieldLabel = uilabel(app.StageControlPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.Step_size = uieditfield(app.StageControlPanel, 'numeric');
            app.Step_size.Value = 1;
            app.mLabel = uilabel(app.StageControlPanel);
            app.mLabel.Text = 'µm';
            app.SnapShotPreviewButton = uibutton(app.AcquisitionTab, 'push');
            app.SnapShotPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotPreviewButtonPushed, true);
            app.SnapShotPreviewButton.Text = 'SnapShot Preview';
            app.ImagePreviewPanel = uipanel(app.AcquisitionTab);
            app.ImagePreviewPanel.Title = 'Image Preview';
            app.Image_Preview = uiimage(app.ImagePreviewPanel);
            app.FilenameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FileName = uieditfield(app.AcquisitionTab, 'text');
            app.ExpTimemsLabel = uilabel(app.AcquisitionTab);
            app.ExpTimemsLabel.Text = 'Exp Time (ms)';
            app.Exposure_time_2 = uieditfield(app.AcquisitionTab, 'numeric');
            app.Exposure_time_2.Limits = [0 5000];
            app.Exposure_time_2.ValueChangedFcn = createCallbackFcn(app, @Exposure_time_2ValueChanged, true);
            app.Exposure_time_2.Value = 400;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ImageSettingsPanel = uipanel(app.SettingsTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.MagnificationLabel = uilabel(app.ImageSettingsPanel);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.ImageSettingsPanel);
            app.MagnificationDropDown.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = '20X';
            app.ExposureTime1Label = uilabel(app.ImageSettingsPanel);
            app.ExposureTime1Label.Text = 'Exposure Time (1):';
            app.ExposureTime1 = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime1.Limits = [0 1500];
            app.ExposureTime1.Value = 50;
            app.msLabel_2 = uilabel(app.ImageSettingsPanel);
            app.msLabel_2.Text = 'ms';
            app.msLabel = uilabel(app.ImageSettingsPanel);
            app.msLabel.Text = 'ms';
            app.ExposureTime2EditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2):';
            app.ExposureTime2 = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime2.Limits = [0 1500];
            app.ExposureTime2.Value = 100;
            app.AcquisitionPanel = uipanel(app.SettingsTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.MicroscopeSetupLabel = uilabel(app.AcquisitionPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.MicroscopeSetupDropDown = uidropdown(app.AcquisitionPanel);
            app.MicroscopeSetupDropDown.Items = {'CCP-BRM', 'qBRM', 'Brightfield Microscopy', 'Rotating Linear Polarizer'};
            app.MicroscopeSetupDropDown.Value = 'CCP-BRM';
            app.AcquisitionTypeLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitionTypeLabel.Text = 'Acquisition Type:';
            app.AcquisitionType = uidropdown(app.AcquisitionPanel);
            app.AcquisitionType.Items = {'Single Image', 'Single Z-Stack', 'Stitching', 'Widefield (4x)'};
            app.AcquisitionType.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTypeValueChanged, true);
            app.AcquisitionType.Value = 'Single Image';
            app.ExposureTimeLabel = uilabel(app.AcquisitionPanel);
            app.ExposureTimeLabel.Text = 'Exposure Time:';
            app.Exposure_time = uieditfield(app.AcquisitionPanel, 'numeric');
            app.Exposure_time.Limits = [0 5000];
            app.Exposure_time.ValueChangedFcn = createCallbackFcn(app, @Exposure_timeValueChanged, true);
            app.Exposure_time.Value = 400;
            app.AutoFocus = uicheckbox(app.AcquisitionPanel);
            app.AutoFocus.Text = 'Auto Focus';
            app.LinearPolarizersPanel = uipanel(app.SettingsTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.Label_3 = uilabel(app.LinearPolarizersPanel);
            app.Label_3.Text = '14.8 °';
            app.Label_4 = uilabel(app.LinearPolarizersPanel);
            app.Label_4.Text = '120.03 ° ';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Rotate_Detection = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection.Text = 'Rotate';
            app.Label_2 = uilabel(app.LinearPolarizersPanel);
            app.Label_2.Text = '°';
            app.Label = uilabel(app.LinearPolarizersPanel);
            app.Label.Text = '°';
            app.DetectionEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionEditFieldLabel.Text = 'Detection';
            app.DetectionEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.DetectionEditField.Limits = [0 180];
            app.IlluminationEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationEditField.Limits = [0 180];
            app.Rotate_Detection_2 = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection_2.Text = 'Rotate';
            app.BrowseButton_2 = uibutton(app.SettingsTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.SaveDirectoryLabel = uilabel(app.SettingsTab);
            app.SaveDirectoryLabel.Text = 'Save Directory:';
            app.SaveDirectory = uieditfield(app.SettingsTab, 'text');
            app.GridLayoutPanel = uipanel(app.SettingsTab);
            app.GridLayoutPanel.Title = 'Grid Layout';
            app.UIAxes = uiaxes(app.GridLayoutPanel);
            title(app.UIAxes, 'Grid Selection')
            xlabel(app.UIAxes, 'X Position')
            ylabel(app.UIAxes, 'Y Position')
            app.TabGroup2 = uitabgroup(app.GridLayoutPanel);
            app.CornersTab = uitab(app.TabGroup2);
            app.CornersTab.Title = '2 Corners';
            app.calculate_grid_button = uibutton(app.CornersTab, 'push');
            app.calculate_grid_button.Tag = 'calculate_grid_button';
            app.calculate_grid_button.Text = 'Calculate Grid Size';
            app.Image_2 = uiimage(app.CornersTab);
            app.Image_2.ImageSource = 'box.png';
            app.set_UL_coords_button = uibutton(app.CornersTab, 'push');
            app.set_UL_coords_button.Tag = 'set_UL_coords_button';
            app.set_UL_coords_button.Text = 'Set UL';
            app.set_LR_coords_button = uibutton(app.CornersTab, 'push');
            app.set_LR_coords_button.Tag = 'set_LR_coords_button';
            app.set_LR_coords_button.Text = 'Set LR';
            app.SingleCornerTab = uitab(app.TabGroup2);
            app.SingleCornerTab.SizeChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.SingleCornerTab.Title = 'Single Corner';
            app.set_grid_button = uibutton(app.SingleCornerTab, 'push');
            app.set_grid_button.Tag = 'set_grid_button';
            app.set_grid_button.Text = 'Set Grid Size';
            app.Image_3 = uiimage(app.SingleCornerTab);
            app.Image_3.ImageSource = 'box.png';
            app.set_up_left_button = uibutton(app.SingleCornerTab, 'push');
            app.set_up_left_button.ButtonPushedFcn = createCallbackFcn(app, @set_up_left_buttonButtonPushed, true);
            app.set_up_left_button.Tag = 'set_up_left_button';
            app.set_up_left_button.Text = 'Set UL';
            app.set_low_right_button = uibutton(app.SingleCornerTab, 'push');
            app.set_low_right_button.Tag = 'set_low_right_button';
            app.set_low_right_button.Text = 'Set LR';
            app.LoadGridButton = uibutton(app.GridLayoutPanel, 'push');
            app.LoadGridButton.Text = 'Load Grid ';
            app.GridSizeLabel = uilabel(app.GridLayoutPanel);
            app.GridSizeLabel.Text = 'Grid Size: ';
            app.Grid_xentry = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Grid_xentry.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.Grid_xentry.Value = 2;
            app.xEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Grid_yentry = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Grid_yentry.ValueChangedFcn = createCallbackFcn(app, @Grid_yentryValueChanged, true);
            app.Grid_yentry.Value = 2;
            app.SetZFocusButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetZFocusButton.ButtonPushedFcn = createCallbackFcn(app, @SetZFocusButtonPushed, true);
            app.SetZFocusButton.Text = 'Set Z-Focus';
            app.StitchingTypeDropDownLabel = uilabel(app.GridLayoutPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingType = uidropdown(app.GridLayoutPanel);
            app.StitchingType.Items = {'Single Plane', 'Multi-Plane'};
            app.StitchingType.Value = 'Single Plane';
            app.Image_Preview_2 = uiimage(app.GridLayoutPanel);
            app.FocusUpButton_2 = uibutton(app.GridLayoutPanel, 'push');
            app.FocusUpButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusUpButtonPushed, true);
            app.FocusUpButton_2.Text = 'Focus Up';
            app.FocusPosLabel_2 = uilabel(app.GridLayoutPanel);
            app.FocusPosLabel_2.Text = 'Focus-Pos';
            app.FocusPos_3 = uilabel(app.GridLayoutPanel);
            app.FocusPos_3.Text = 'NaN';
            app.FocusDownButton_2 = uibutton(app.GridLayoutPanel, 'push');
            app.FocusDownButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusDownButtonPushed, true);
            app.FocusDownButton_2.Text = 'Focus Down';
            app.mLabel_2 = uilabel(app.GridLayoutPanel);
            app.mLabel_2.Text = 'µm';
            app.StepSizeEditFieldLabel_2 = uilabel(app.GridLayoutPanel);
            app.StepSizeEditFieldLabel_2.Text = 'Step Size';
            app.Step_size_2 = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Step_size_2.Value = 1;
            app.ExpTimemsLabel_2 = uilabel(app.GridLayoutPanel);
            app.ExpTimemsLabel_2.Text = 'Exp Time (ms)';
            app.Exposure_time_3 = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Exposure_time_3.Limits = [0 5000];
            app.Exposure_time_3.Value = 400;
            app.DownButton = uibutton(app.GridLayoutPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.GridLayoutPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.UpButton = uibutton(app.GridLayoutPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.GridLayoutPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.SetZFocusforPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetZFocusforPositionButton.Enable = 'off';
            app.SetZFocusforPositionButton.Text = 'Set Z-Focus for Position ';
            app.Label_6 = uilabel(app.GridLayoutPanel);
            app.Label_6.Text = '_________________________________________________________________________________________________________________________';
            app.UnSelectPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.UnSelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectPositionButtonPushed, true);
            app.UnSelectPositionButton.Text = 'Un-Select Position';
            app.SelectPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.SelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPositionButtonPushed, true);
            app.SelectPositionButton.Text = 'Select Position';
            app.Camera_view_2 = uibutton(app.GridLayoutPanel, 'push');
            app.Camera_view_2.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view_2.Text = 'Change Camera View';
            app.Camera_status_label_2 = uilabel(app.GridLayoutPanel);
            app.Camera_status_label_2.Text = 'Camera Status: ';
            app.Current_camera_2 = uilabel(app.GridLayoutPanel);
            app.Current_camera_2.Text = 'Micromanager';
            app.GridSelectionDropDownLabel = uilabel(app.GridLayoutPanel);
            app.GridSelectionDropDownLabel.Text = 'Grid Selection';
            app.GridSelectionDropDown = uidropdown(app.GridLayoutPanel);
            app.GridSelectionDropDown.Items = {'Single Corner', 'Two Corners'};
            app.GridSelectionDropDown.Value = 'Single Corner';
            app.SetUpperLeftButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetUpperLeftButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpperLeftButtonPushed, true);
            app.SetUpperLeftButton.Text = 'Set Upper Left';
            app.SetLowerRightButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetLowerRightButton.ButtonPushedFcn = createCallbackFcn(app, @SetLowerRightButtonPushed, true);
            app.SetLowerRightButton.Enable = 'off';
            app.SetLowerRightButton.Text = 'Set Lower Right';
            app.UpperLeftLabel = uilabel(app.GridLayoutPanel);
            app.UpperLeftLabel.Text = 'Upper Left:';
            app.UpperRightLabel = uilabel(app.GridLayoutPanel);
            app.UpperRightLabel.Text = 'Upper Right:';
            app.UL_Location_lbl = uilabel(app.GridLayoutPanel);
            app.UL_Location_lbl.Text = '(55,37.5)';
            app.NALabel = uilabel(app.GridLayoutPanel);
            app.NALabel.Text = '(NA)';
            app.SampleParametersPanel = uipanel(app.SettingsTab);
            app.SampleParametersPanel.Title = 'Sample Parameters';
            app.SampleThicknessEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleThicknessEditFieldLabel.Text = 'Sample Thickness';
            app.SampleThickness = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleThickness.Limits = [-10 Inf];
            app.SampleThickness.Value = 30;
            app.mLabel_3 = uilabel(app.SampleParametersPanel);
            app.mLabel_3.Text = 'µm';
            app.SampleTypeEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleTypeEditFieldLabel.Text = 'Sample Type';
            app.SampleTypeEditField = uieditfield(app.SampleParametersPanel, 'text');
            app.SampleTypeEditField.Value = 'Human';
            app.mLabel_4 = uilabel(app.SampleParametersPanel);
            app.mLabel_4.Text = 'µm';
            app.SamplethicknessOffsetLabel = uilabel(app.SampleParametersPanel);
            app.SamplethicknessOffsetLabel.Text = '*Sample thickness ± Offset  ';
            app.mLabel_5 = uilabel(app.SampleParametersPanel);
            app.mLabel_5.Text = 'µm';
            app.ZStackOffsetLabel = uilabel(app.SampleParametersPanel);
            app.ZStackOffsetLabel.Text = 'Z-Stack Offset';
            app.ZStackoffset = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSizeLabel = uilabel(app.SampleParametersPanel);
            app.ZStackStepSizeLabel.Enable = 'off';
            app.ZStackStepSizeLabel.Text = 'Z-Stack Step Size';
            app.ZStackStepSize = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSize.Limits = [0 Inf];
            app.ZStackStepSize.Editable = 'off';
            app.ZStackStepSize.Enable = 'off';
            app.ZStackStepSize.Value = 1;
            app.OpenMicroManagerButton = uibutton(app.SettingsTab, 'push');
            app.OpenMicroManagerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMicroManagerButtonPushed, true);
            app.OpenMicroManagerButton.Text = 'Open Micro-Manager';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRM_Microscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end